classdef matlab_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LeafPestDetectionLabel  matlab.ui.control.Label
        TODAYSDatePicker        matlab.ui.control.DatePicker
        TODAYSDatePickerLabel   matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        SelectImageButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    trainedNet
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            matFile = fullfile(fileparts(mfilename('fullpath')), 'leafPestNet.mat');
S = load(matFile);
app.trainedNet = S.trainedNet;

cla(app.UIAxes);
axis(app.UIAxes,'off');

app.ResultLabel.Text = 'Result: -';

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function: not associated with a component
        function SelectImageButtonPushed(app, event)
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed2(app, event)
           
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Select Leaf Image');
if isequal(file,0)
    return;
end

img = imread(fullfile(path,file));

imshow(img,'Parent',app.UIAxes);
axis(app.UIAxes,'off');

img2 = imresize(img,[227 227]);
if size(img2,3) == 1
    img2 = cat(3,img2,img2,img2);
end

[label, scores] = classify(app.trainedNet, img2);
confidence = 100 * max(scores);
app.ResultLabel.Text = sprintf('Result: %s (%.1f%%)', char(label), confidence);
if strcmpi(char(label),'healthy')
    app.ResultLabel.FontColor = [0 0.5 0];   % green
else
    app.ResultLabel.FontColor = [0.8 0 0];   % red
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[]'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed2, true);
            app.SelectImageButton.Text = 'Select Leaf Image';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.TODAYSDatePickerLabel = uilabel(app.UIFigure);
            app.TODAYSDatePickerLabel.Text = 'TODAY İS:';
            app.TODAYSDatePicker = uidatepicker(app.UIFigure);
            app.TODAYSDatePicker.Value = datetime([2025 12 24]);
            app.LeafPestDetectionLabel = uilabel(app.UIFigure);
            app.LeafPestDetectionLabel.Text = 'Leaf Pest Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end